classdef CM_AppDesigner_FSCV < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Menu               matlab.ui.container.Menu
        LoadMenu           matlab.ui.container.Menu
        LampPlot4          matlab.ui.control.Lamp
        Lamp_4Label        matlab.ui.control.Label
        LampPlot3          matlab.ui.control.Lamp
        Lamp_3Label        matlab.ui.control.Label
        LampPlot2          matlab.ui.control.Lamp
        Lamp_2Label        matlab.ui.control.Label
        LampPlot1          matlab.ui.control.Lamp
        Label              matlab.ui.control.Label
        ChDropDown4        matlab.ui.control.DropDown
        ChDropDownLabel_3  matlab.ui.control.Label
        ChDropDown3        matlab.ui.control.DropDown
        ChDropDown_2Label  matlab.ui.control.Label
        ChDropDown2        matlab.ui.control.DropDown
        ChDropDownLabel_2  matlab.ui.control.Label
        ChDropDown1        matlab.ui.control.DropDown
        ChDropDownLabel    matlab.ui.control.Label
        DataTable          matlab.ui.control.Table
        TitleText          matlab.ui.control.EditField
        CvPlot             matlab.ui.control.UIAxes
        DopaminePlot       matlab.ui.control.UIAxes
        ColorPlot4         matlab.ui.control.UIAxes
        ColorPlot3         matlab.ui.control.UIAxes
        ColorPlot2         matlab.ui.control.UIAxes
        ColorPlot1         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        
        numOfChannels;
        channelsToDisplay;
        indicesOfColorPlots;
        appDesColorPlots; 
        fscvColors;             % currently only 4 * 3 (rgb)
                                % need 32 * 3
        anodalLimit;
        cathodalLimit;

    end
    
    methods (Access = private)

        function startScript(app)

            % lamp start-up color
            app.LampPlot1.Color = [0, 0, 0]; 
            app.LampPlot2.Color = [0, 0, 0];
            app.LampPlot3.Color = [0, 0, 0];
            app.LampPlot4.Color = [0, 0, 0]; 

            % make app invisible until uigetfile is displayed
            app.UIFigure.Visible = 'off'; 

            % get the file here
            [selectedFileName,selectedPathName] = ... 
                        uigetfile('*.*', 'Select file');

            app.UIFigure.Visible = 'on'; 
            
            % create color plot array
            app.appDesColorPlots = [app.ColorPlot1, app.ColorPlot2, ... 
                                    app.ColorPlot3, app.ColorPlot4];
                        
            app.channelsToDisplay = 1:4;
            app.indicesOfColorPlots = 1:4;
            app.setInputChannelsTo4();

            CM_plotEphysFSCVmulti(app, app.UIFigure, ...
                            selectedFileName, selectedPathName); 
                   
            % align the dopamine plot to the first color plot
            app.DopaminePlot.InnerPosition(1) = app.ColorPlot1.InnerPosition(1);
            app.DopaminePlot.InnerPosition(3) = app.ColorPlot1.InnerPosition(3);
        
            % update the lamps to reflect the initial channels
            app.updateLampColors();
        end
    end
    
    methods (Access = public)
        
        function setInputChannelsTo4(app)

            channelNumsString = [ "X", string(1:4) ];
            app.ChDropDown1.Items = channelNumsString;
            app.ChDropDown2.Items = channelNumsString;
            app.ChDropDown3.Items = channelNumsString;
            app.ChDropDown4.Items = channelNumsString;

            app.ChDropDown1.Value = '1';
            app.ChDropDown2.Value = '2';
            app.ChDropDown3.Value = '3';
            app.ChDropDown4.Value = '4';

            app.numOfChannels = 4;
        end

        function setInputChannelsTo32(app)
            
            channelNumsString = [ "X", string(1:32) ];
            app.ChDropDown1.Items = channelNumsString;
            app.ChDropDown2.Items = channelNumsString;
            app.ChDropDown3.Items = channelNumsString;
            app.ChDropDown4.Items = channelNumsString;

            app.ChDropDown1.Value = '1';
            app.ChDropDown2.Value = '2';
            app.ChDropDown3.Value = '3';
            app.ChDropDown4.Value = '4';

            app.numOfChannels = 32;
        end
        
        function updateChannelsSelected(app)
        
            dropCh1 = string( app.ChDropDown1.Value );    
            dropCh2 = string( app.ChDropDown2.Value );    
            dropCh3 = string( app.ChDropDown3.Value );    
            dropCh4 = string( app.ChDropDown4.Value );    

            selectedChannels = [dropCh1; dropCh2; dropCh3; dropCh4];

            displayChannels = [];
            displayIndices = [];

            for i = 1 : 4
                channelValue = selectedChannels(i);

                if ~strcmp(channelValue, "X")
                    displayChannels = [displayChannels, str2num(channelValue)]; %#ok<ST2NM,AGROW> 
                    displayIndices = [displayIndices, i]; %#ok<AGROW> 
                end
            end    

            app.channelsToDisplay = displayChannels;
            app.indicesOfColorPlots = displayIndices;

            app.updateLampColors();
            disp("Update Channel Selected Called");
            disp(app.channelsToDisplay);
            disp(app.indicesOfColorPlots);
        end

        function obj = getColorPlots(app)
            obj = app.appDesColorPlots;
        end

        function obj = getLastColorPlot(app)
            obj = app.ColorPlot4;
        end    

        function obj = getDopaminePlot(app)
            obj = app.DopaminePlot;
        end

        function obj = getCvPlot(app)
            obj = app.CvPlot;
        end

        function obj = getDataTable(app) 
            obj = app.DataTable;
        end    

        function obj = getTitleText(app)
            obj = app.TitleText;
        end   

        function obj = getChannelsToDisplay(app)
            obj = app.channelsToDisplay;
        end

        function obj = getIndicesOfColorPlots(app) 
            obj = app.indicesOfColorPlots;
        end    

        function setfscvColors(app, fscvColors)
            app.fscvColors = fscvColors;
            
            sizeOfColors = size(fscvColors);
            disp(sizeOfColors);
        end    

        function updateLampColors(app)

            selectedChannels = [app.ChDropDown1, app.ChDropDown2, ... 
                                app.ChDropDown3, app.ChDropDown4];
                       
            lamps = [app.LampPlot1, app.LampPlot2, ... 
                     app.LampPlot3, app.LampPlot4];
                            
            for i = 1:4
                channelValue = selectedChannels(i).Value;
                lamp = lamps(i);

                % grey out 
                if strcmp(channelValue, "X")
                    lamp.Color = [0, 0, 0];

                % set color    
                else    
                    channelValueNum = str2num(channelValue); %#ok<ST2NM> 
                    lamp.Color = app.fscvColors(channelValueNum, :);
                end    
            end    
        end  

        function value = getNumOfChannels(app)
            value = app.numOfChannels;
        end

        function setAnodalLimit(app, anodalLimit)
            app.anodalLimit = anodalLimit;
        end

        function setCathodalLimit(app, cathodalLimit)
            app.cathodalLimit = cathodalLimit;
        end    

        function val = getAnodalLimit(app)
            val = app.anodalLimit;
        end

        function val = getCathodalLimit(app)
            val = app.cathodalLimit;
        end    

    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % call the start script
            startScript(app);
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            
            keyPressed = event.Key;            
            CM_buttonpress(app, keyPressed);
            
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
           
            % figure -> axes -> image
            figureObj = event.Source;
            childObj = event.Source.CurrentObject;
            
            % Values - 'normal' (left), 'alt' (right), 'extend' (center)
            typeOfClick = figureObj.SelectionType;

            % [x, y] pixels
            clickPosOnFigure = figureObj.CurrentPoint;

            % if there is a child object and it is an image
            if ~isempty(childObj) && strcmp(childObj.Type, 'image')
           
                % get the plot position from the parent (axis object)
                plotPosOnFigure = childObj.Parent.InnerPosition;

                % [x, y] - [left bottom width height]       
                clickPosOnPlot = ...
                 [clickPosOnFigure(1) - plotPosOnFigure(1),...
                  clickPosOnFigure(2) - plotPosOnFigure(2)];
                
                % call mouse handler function
                CM_mouseclick(app, typeOfClick, plotPosOnFigure, ...
                              clickPosOnPlot);

            end
        end

        % Menu selected function: LoadMenu
        function LoadMenuSelected(app, event)
           
            CM_menuselect(app);
            
        end

        % Value changed function: ChDropDown1
        function ChDropDown1ValueChanged(app, event)
            
            app.updateChannelsSelected();
            CM_updatePlots(app);

        end

        % Value changed function: ChDropDown2
        function ChDropDown2ValueChanged(app, event)
            
            app.updateChannelsSelected();
            CM_updatePlots(app);

        end

        % Value changed function: ChDropDown3
        function ChDropDown3ValueChanged(app, event)
            
            app.updateChannelsSelected();
            CM_updatePlots(app);

        end

        % Value changed function: ChDropDown4
        function ChDropDown4ValueChanged(app, event)
            
            app.updateChannelsSelected();
            CM_updatePlots(app);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.LoadMenu = uimenu(app.Menu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.LoadMenu.Text = 'Load';
            app.ColorPlot1 = uiaxes(app.UIFigure);
            ylabel(app.ColorPlot1, 'Voltage (V)')
            zlabel(app.ColorPlot1, 'Z')
            app.ColorPlot1.XLimitMethod = 'tight';
            app.ColorPlot1.YLimitMethod = 'tight';
            app.ColorPlot1.XTick = [];
            app.ColorPlot1.XTickLabel = '';
            app.ColorPlot1.YTick = [];
            app.ColorPlot1.Tag = 'ColorPlot1';
            app.ColorPlot2 = uiaxes(app.UIFigure);
            ylabel(app.ColorPlot2, 'Voltage (V)')
            zlabel(app.ColorPlot2, 'Z')
            app.ColorPlot2.XLimitMethod = 'tight';
            app.ColorPlot2.YLimitMethod = 'tight';
            app.ColorPlot2.XTick = [];
            app.ColorPlot2.XTickLabel = '';
            app.ColorPlot2.YTick = [];
            app.ColorPlot2.Tag = 'ColorPlot2';
            app.ColorPlot3 = uiaxes(app.UIFigure);
            ylabel(app.ColorPlot3, 'Voltage (V)')
            zlabel(app.ColorPlot3, 'Z')
            app.ColorPlot3.XLimitMethod = 'tight';
            app.ColorPlot3.YLimitMethod = 'tight';
            app.ColorPlot3.XTick = [];
            app.ColorPlot3.XTickLabel = '';
            app.ColorPlot3.YTick = [];
            app.ColorPlot3.Tag = 'ColorPlot3';
            app.ColorPlot4 = uiaxes(app.UIFigure);
            xlabel(app.ColorPlot4, 'Time (s)')
            ylabel(app.ColorPlot4, 'Voltage (V)')
            zlabel(app.ColorPlot4, 'Z')
            app.ColorPlot4.XLimitMethod = 'tight';
            app.ColorPlot4.YLimitMethod = 'tight';
            app.ColorPlot4.XTick = [];
            app.ColorPlot4.XTickLabel = '';
            app.ColorPlot4.YTick = [];
            app.ColorPlot4.Tag = 'ColorPlot4';
            app.DopaminePlot = uiaxes(app.UIFigure);
            ylabel(app.DopaminePlot, 'DM')
            zlabel(app.DopaminePlot, 'Z')
            app.DopaminePlot.XTick = [];
            app.DopaminePlot.XTickLabel = '';
            app.CvPlot = uiaxes(app.UIFigure);
            title(app.CvPlot, 'CV at selected timepoint')
            xlabel(app.CvPlot, 'E_a (V)')
            ylabel(app.CvPlot, 'I (nA)')
            zlabel(app.CvPlot, 'Z')
            app.CvPlot.XLim = [-0.4 1.3];
            app.CvPlot.XTick = [-0.4 -0.2 0 0.2 0.4 0.6 0.8 1 1.3];
            app.CvPlot.XTickLabel = {'-0.4'; '-0.2'; '0'; '0.2'; '0.4'; '0.6'; '0.8'; '1'; '1.3'};
            app.TitleText = uieditfield(app.UIFigure, 'text');
            app.DataTable = uitable(app.UIFigure);
            app.DataTable.ColumnName = {'Ch1'; 'Ch2'; 'Ch3'; 'Ch4'};
            app.DataTable.RowName = {};
            app.ChDropDownLabel = uilabel(app.UIFigure);
            app.ChDropDownLabel.Text = 'Ch';
            app.ChDropDown1 = uidropdown(app.UIFigure);
            app.ChDropDown1.Items = {};
            app.ChDropDown1.ValueChangedFcn = createCallbackFcn(app, @ChDropDown1ValueChanged, true);
            app.ChDropDown1.Value = {};
            app.ChDropDownLabel_2 = uilabel(app.UIFigure);
            app.ChDropDownLabel_2.Text = 'Ch';
            app.ChDropDown2 = uidropdown(app.UIFigure);
            app.ChDropDown2.Items = {};
            app.ChDropDown2.ValueChangedFcn = createCallbackFcn(app, @ChDropDown2ValueChanged, true);
            app.ChDropDown2.Value = {};
            app.ChDropDown_2Label = uilabel(app.UIFigure);
            app.ChDropDown_2Label.Text = 'Ch';
            app.ChDropDown3 = uidropdown(app.UIFigure);
            app.ChDropDown3.Items = {};
            app.ChDropDown3.ValueChangedFcn = createCallbackFcn(app, @ChDropDown3ValueChanged, true);
            app.ChDropDown3.Value = {};
            app.ChDropDownLabel_3 = uilabel(app.UIFigure);
            app.ChDropDownLabel_3.Text = 'Ch';
            app.ChDropDown4 = uidropdown(app.UIFigure);
            app.ChDropDown4.Items = {};
            app.ChDropDown4.ValueChangedFcn = createCallbackFcn(app, @ChDropDown4ValueChanged, true);
            app.ChDropDown4.Value = {};
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.LampPlot1 = uilamp(app.UIFigure);
            app.Lamp_2Label = uilabel(app.UIFigure);
            app.Lamp_2Label.Text = '';
            app.LampPlot2 = uilamp(app.UIFigure);
            app.Lamp_3Label = uilabel(app.UIFigure);
            app.Lamp_3Label.Text = '';
            app.LampPlot3 = uilamp(app.UIFigure);
            app.Lamp_4Label = uilabel(app.UIFigure);
            app.Lamp_4Label.Text = '';
            app.LampPlot4 = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CM_AppDesigner_FSCV

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end